classdef prueba_sensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OKButton               matlab.ui.control.Button
        ImportadoLamp          matlab.ui.control.Lamp
        ImportadoLampLabel     matlab.ui.control.Label
        DesconectarButton      matlab.ui.control.Button
        ConexinLamp            matlab.ui.control.Lamp
        ConexinLampLabel       matlab.ui.control.Label
        ConectarButton         matlab.ui.control.Button
        BaudiosEditField       matlab.ui.control.NumericEditField
        BaudiosEditFieldLabel  matlab.ui.control.Label
        PuertoEditField        matlab.ui.control.EditField
        PuertoEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        comu
        cntData=0;
        msg;
        elong;
    end
    
    methods (Access = private)
        
        function read_serialData(app, src, event)
            str=readline(app.comu); %Lee código ASCII que le envía Arduino
            if str == "finish"
                assignin("base","datos",app.msg);
                assignin("base","delta_x",app.elong);
                app.cntData = 0;
                app.ImportadoLamp.Color = 'green';
            else
                s_str = split(str);
                app.cntData = app.cntData+1; %Contador de datos recibidos
                app.msg(app.cntData) = str2double(s_str(1));
                app.elong(app.cntData) = str2double(s_str(2));
            end
           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            app.comu = serialport('COM3',9600); %Inicializacion por defecto
            configureTerminator(app.comu,'CR/LF');
            app.ConexinLamp.Color = 'green';
            % Configuración para la recepción de mensajes
            configureCallback(app.comu,"terminator",@app.read_serialData);
            app.BaudiosEditField.Value = 9600;
            app.PuertoEditField.Value = "COM3";
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            app.ConexinLamp.Color = 'red';
            clear app.comu;
            app.comu = [];
            clear app.msg;
            clear app.elong;
            app.cntData = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ConexinLamp.Color = 'red';
            clear app.comu;
            app.comu = []; % Eliminamos la conexión y borramos el puerto
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed2(app, event)
            writeline(app.comu,"1");
            clear app.msg;
            clear app.elong;
            app.cntData = 0;
            app.ImportadoLamp.Color = 'red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.UIFigure, 'text');
            app.BaudiosEditFieldLabel = uilabel(app.UIFigure);
            app.BaudiosEditFieldLabel.Text = 'Baudios';
            app.BaudiosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConexinLampLabel = uilabel(app.UIFigure);
            app.ConexinLampLabel.Text = 'Conexión';
            app.ConexinLamp = uilamp(app.UIFigure);
            app.DesconectarButton = uibutton(app.UIFigure, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.ImportadoLampLabel = uilabel(app.UIFigure);
            app.ImportadoLampLabel.Text = 'Importado';
            app.ImportadoLamp = uilamp(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed2, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba_sensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end